classdef gui_optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StrategyTab                     matlab.ui.container.Tab
        TFPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_4           matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_2    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_4                matlab.ui.control.CheckBox
        LocalYCheckBox_4                matlab.ui.control.CheckBox
        LocalZCheckBox_4                matlab.ui.control.CheckBox
        MagnitudeCheckBox_4             matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_7                matlab.ui.control.CheckBox
        LocalYCheckBox_7                matlab.ui.control.CheckBox
        LocalZCheckBox_7                matlab.ui.control.CheckBox
        MagnitudeCheckBox_7             matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_8                matlab.ui.control.CheckBox
        LocalYCheckBox_8                matlab.ui.control.CheckBox
        LocalZCheckBox_8                matlab.ui.control.CheckBox
        MagnitudeCheckBox_8             matlab.ui.control.CheckBox
        Stage1DropDownLabel             matlab.ui.control.Label
        Stage1DropDown                  matlab.ui.control.DropDown
        Stage2DropDownLabel             matlab.ui.control.Label
        Stage2DropDown                  matlab.ui.control.DropDown
        Stage3DropDownLabel             matlab.ui.control.Label
        Stage3DropDown                  matlab.ui.control.DropDown
        ArPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_6           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_12               matlab.ui.control.CheckBox
        LocalYCheckBox_12               matlab.ui.control.CheckBox
        LocalZCheckBox_12               matlab.ui.control.CheckBox
        MagnitudeCheckBox_12            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_13               matlab.ui.control.CheckBox
        LocalYCheckBox_13               matlab.ui.control.CheckBox
        LocalZCheckBox_13               matlab.ui.control.CheckBox
        MagnitudeCheckBox_13            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_14               matlab.ui.control.CheckBox
        LocalYCheckBox_14               matlab.ui.control.CheckBox
        LocalZCheckBox_14               matlab.ui.control.CheckBox
        MagnitudeCheckBox_14            matlab.ui.control.CheckBox
        TAPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_5           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_9                matlab.ui.control.CheckBox
        LocalYCheckBox_9                matlab.ui.control.CheckBox
        LocalZCheckBox_9                matlab.ui.control.CheckBox
        MagnitudeCheckBox_9             matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_10               matlab.ui.control.CheckBox
        LocalYCheckBox_10               matlab.ui.control.CheckBox
        LocalZCheckBox_10               matlab.ui.control.CheckBox
        MagnitudeCheckBox_10            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_11               matlab.ui.control.CheckBox
        LocalYCheckBox_11               matlab.ui.control.CheckBox
        LocalZCheckBox_11               matlab.ui.control.CheckBox
        MagnitudeCheckBox_11            matlab.ui.control.CheckBox
        TFPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_7           matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_3    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_3  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_4  matlab.ui.container.Panel
        LocalXCheckBox_15               matlab.ui.control.CheckBox
        LocalYCheckBox_15               matlab.ui.control.CheckBox
        LocalZCheckBox_15               matlab.ui.control.CheckBox
        MagnitudeCheckBox_15            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_4  matlab.ui.container.Panel
        LocalXCheckBox_16               matlab.ui.control.CheckBox
        LocalYCheckBox_16               matlab.ui.control.CheckBox
        LocalZCheckBox_16               matlab.ui.control.CheckBox
        MagnitudeCheckBox_16            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_4  matlab.ui.container.Panel
        LocalXCheckBox_17               matlab.ui.control.CheckBox
        LocalYCheckBox_17               matlab.ui.control.CheckBox
        LocalZCheckBox_17               matlab.ui.control.CheckBox
        MagnitudeCheckBox_17            matlab.ui.control.CheckBox
        TAPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_8           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_3  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_3  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_5  matlab.ui.container.Panel
        LocalXCheckBox_18               matlab.ui.control.CheckBox
        LocalYCheckBox_18               matlab.ui.control.CheckBox
        LocalZCheckBox_18               matlab.ui.control.CheckBox
        MagnitudeCheckBox_18            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_5  matlab.ui.container.Panel
        LocalXCheckBox_19               matlab.ui.control.CheckBox
        LocalYCheckBox_19               matlab.ui.control.CheckBox
        LocalZCheckBox_19               matlab.ui.control.CheckBox
        MagnitudeCheckBox_19            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_5  matlab.ui.container.Panel
        LocalXCheckBox_20               matlab.ui.control.CheckBox
        LocalYCheckBox_20               matlab.ui.control.CheckBox
        LocalZCheckBox_20               matlab.ui.control.CheckBox
        MagnitudeCheckBox_20            matlab.ui.control.CheckBox
        ArPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_9           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_3  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_3  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_6  matlab.ui.container.Panel
        LocalXCheckBox_21               matlab.ui.control.CheckBox
        LocalYCheckBox_21               matlab.ui.control.CheckBox
        LocalZCheckBox_21               matlab.ui.control.CheckBox
        MagnitudeCheckBox_21            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_6  matlab.ui.container.Panel
        LocalXCheckBox_22               matlab.ui.control.CheckBox
        LocalYCheckBox_22               matlab.ui.control.CheckBox
        LocalZCheckBox_22               matlab.ui.control.CheckBox
        MagnitudeCheckBox_22            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_6  matlab.ui.container.Panel
        LocalXCheckBox_23               matlab.ui.control.CheckBox
        LocalYCheckBox_23               matlab.ui.control.CheckBox
        LocalZCheckBox_23               matlab.ui.control.CheckBox
        MagnitudeCheckBox_23            matlab.ui.control.CheckBox
        TFPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_10          matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_4    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_4  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_7  matlab.ui.container.Panel
        LocalXCheckBox_24               matlab.ui.control.CheckBox
        LocalYCheckBox_24               matlab.ui.control.CheckBox
        LocalZCheckBox_24               matlab.ui.control.CheckBox
        MagnitudeCheckBox_24            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_7  matlab.ui.container.Panel
        LocalXCheckBox_25               matlab.ui.control.CheckBox
        LocalYCheckBox_25               matlab.ui.control.CheckBox
        LocalZCheckBox_25               matlab.ui.control.CheckBox
        MagnitudeCheckBox_25            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_7  matlab.ui.container.Panel
        LocalXCheckBox_26               matlab.ui.control.CheckBox
        LocalYCheckBox_26               matlab.ui.control.CheckBox
        LocalZCheckBox_26               matlab.ui.control.CheckBox
        MagnitudeCheckBox_26            matlab.ui.control.CheckBox
        TAPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_11          matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_4  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_4  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_8  matlab.ui.container.Panel
        LocalXCheckBox_27               matlab.ui.control.CheckBox
        LocalYCheckBox_27               matlab.ui.control.CheckBox
        LocalZCheckBox_27               matlab.ui.control.CheckBox
        MagnitudeCheckBox_27            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_8  matlab.ui.container.Panel
        LocalXCheckBox_28               matlab.ui.control.CheckBox
        LocalYCheckBox_28               matlab.ui.control.CheckBox
        LocalZCheckBox_28               matlab.ui.control.CheckBox
        MagnitudeCheckBox_28            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_8  matlab.ui.container.Panel
        LocalXCheckBox_29               matlab.ui.control.CheckBox
        LocalYCheckBox_29               matlab.ui.control.CheckBox
        LocalZCheckBox_29               matlab.ui.control.CheckBox
        MagnitudeCheckBox_29            matlab.ui.control.CheckBox
        ArPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_12          matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_4  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_4  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_9  matlab.ui.container.Panel
        LocalXCheckBox_30               matlab.ui.control.CheckBox
        LocalYCheckBox_30               matlab.ui.control.CheckBox
        LocalZCheckBox_30               matlab.ui.control.CheckBox
        MagnitudeCheckBox_30            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_9  matlab.ui.container.Panel
        LocalXCheckBox_31               matlab.ui.control.CheckBox
        LocalYCheckBox_31               matlab.ui.control.CheckBox
        LocalZCheckBox_31               matlab.ui.control.CheckBox
        MagnitudeCheckBox_31            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_9  matlab.ui.container.Panel
        LocalXCheckBox_32               matlab.ui.control.CheckBox
        LocalYCheckBox_32               matlab.ui.control.CheckBox
        LocalZCheckBox_32               matlab.ui.control.CheckBox
        MagnitudeCheckBox_32            matlab.ui.control.CheckBox
        ConstraintsTab_2                matlab.ui.container.Tab
        KEDcriteriaCheckBox             matlab.ui.control.CheckBox
        percentageEditFieldLabel        matlab.ui.control.Label
        percentageEditField             matlab.ui.control.NumericEditField
        MountloactionorientationButton  matlab.ui.control.Button
        NaturalFrequenciesPanel         matlab.ui.container.Panel
        LongitudinalLabel_3             matlab.ui.control.Label
        LateralLabel_4                  matlab.ui.control.Label
        BounceLabel_4                   matlab.ui.control.Label
        RollLabel_4                     matlab.ui.control.Label
        PitchLabel_4                    matlab.ui.control.Label
        BounceLabel_5                   matlab.ui.control.Label
        EditField_31                    matlab.ui.control.NumericEditField
        LowerHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        UpperHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        StaticDeflectionsPanel          matlab.ui.container.Panel
        LongitudinalLabel_2             matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        LateralLabel_3                  matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        BounceLabel_3                   matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        RollLabel_3                     matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        PitchLabel_3                    matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        YawLabel_3                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        UITable_purt                    matlab.ui.control.Table
        OptionsTab                      matlab.ui.container.Tab
        Stage1Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_4Label       matlab.ui.control.Label
        swarmsizeEditField_4            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_4Label     matlab.ui.control.Label
        MaxFuncEvalEditField_4          matlab.ui.control.NumericEditField
        FuncTolEditField_4Label         matlab.ui.control.Label
        FuncTolEditField_4              matlab.ui.control.NumericEditField
        MaxIterEditField_4Label         matlab.ui.control.Label
        MaxIterEditField_4              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_4Label   matlab.ui.control.Label
        PenFuncWeightEditField_4        matlab.ui.control.NumericEditField
        Stage2Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_5Label       matlab.ui.control.Label
        swarmsizeEditField_5            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_5Label     matlab.ui.control.Label
        MaxFuncEvalEditField_5          matlab.ui.control.NumericEditField
        FuncTolEditField_5Label         matlab.ui.control.Label
        FuncTolEditField_5              matlab.ui.control.NumericEditField
        MaxIterEditField_5Label         matlab.ui.control.Label
        MaxIterEditField_5              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_5Label   matlab.ui.control.Label
        PenFuncWeightEditField_5        matlab.ui.control.NumericEditField
        Stage3Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_6Label       matlab.ui.control.Label
        swarmsizeEditField_6            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_6Label     matlab.ui.control.Label
        MaxFuncEvalEditField_6          matlab.ui.control.NumericEditField
        FuncTolEditField_6Label         matlab.ui.control.Label
        FuncTolEditField_6              matlab.ui.control.NumericEditField
        MaxIterEditField_6Label         matlab.ui.control.Label
        MaxIterEditField_6              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_6Label   matlab.ui.control.Label
        PenFuncWeightEditField_6        matlab.ui.control.NumericEditField
        CloseButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OptimizationLabel_2             matlab.ui.control.Label
        OptimizationFileEditFieldLabel  matlab.ui.control.Label
        OptimizationFileEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 load(['SavedResults/','gui_curr.mat']);
            
                load(['SavedResults/',gui_curr.opt_name]);
                
                h = eval(gui_curr.opt_name(1:end-4));
                
                app.OptimizationFileEditField.Value = gui_curr.opt_name;
                app.OptimizationFileEditField.Editable = 'on';
                app.Stage1DropDown.Value = h.stage1.type;
                app.Stage2DropDown.Value = h.stage2.type;
                app.Stage3DropDown.Value = h.stage3.type;
                
                Stage1DropDownValueChanged(app);
                Stage2DropDownValueChanged(app);
                Stage3DropDownValueChanged(app);
                
                app.percentageEditField.Value = h.KED.per;
                
                app.EditField_31.Value = h.lb_freq(1);
                app.EditField_33.Value = h.lb_freq(2);
                app.EditField_34.Value = h.lb_freq(3);
                app.EditField_35.Value = h.lb_freq(4);
                app.EditField_36.Value = h.lb_freq(5);
                app.EditField_37.Value = h.lb_freq(6);
                
                
                app.EditField_32.Value = h.ub_freq(1);
                app.EditField_38.Value = h.ub_freq(2);
                app.EditField_39.Value = h.ub_freq(3);
                app.EditField_40.Value = h.ub_freq(4);
                app.EditField_41.Value = h.ub_freq(5);
                app.EditField_42.Value = h.ub_freq(6);
                
                app.EditField_25.Value = h.DeltaStatic(1);
                app.EditField_26.Value = h.DeltaStatic(2);
                app.EditField_27.Value = h.DeltaStatic(3);
                app.EditField_28.Value = h.DeltaStatic(4);
                app.EditField_29.Value = h.DeltaStatic(5);
                app.EditField_30.Value = h.DeltaStatic(6);
                
                app.Stage1Panel_2.Visible = 'off';
                app.Stage2Panel_2.Visible = 'off';
                app.Stage3Panel_2.Visible = 'off';
                if strcmp(h.stage1.type,'None')==0
                    app.Stage1Panel_2.Visible = 'on';
                app.swarmsizeEditField_4.Value = h.stage1.swarmsize;
                app.MaxFuncEvalEditField_4.Value = h.stage1.MaxFuncEval;
                app.FuncTolEditField_4.Value = h.stage1.FuncTol;
                app.MaxIterEditField_4.Value = h.stage1.MaxIter;
                app.PenFuncWeightEditField_4.Value = h.stage1.PenFuncWeight;
                end
                if strcmp(h.stage2.type,'None')==0
                    app.Stage2Panel_2.Visible = 'on';
                app.swarmsizeEditField_5.Value = h.stage2.swarmsize;
                app.MaxFuncEvalEditField_5.Value = h.stage2.MaxFuncEval;
                app.FuncTolEditField_5.Value = h.stage2.FuncTol;
                app.MaxIterEditField_5.Value = h.stage2.MaxIter;
                app.PenFuncWeightEditField_5.Value = h.stage2.PenFuncWeight;
                end
                if strcmp(h.stage3.type,'None')==0
                    app.Stage3Panel_2.Visible = 'on';
                app.swarmsizeEditField_6.Value = h.stage3.swarmsize;
                app.MaxFuncEvalEditField_6.Value = h.stage3.MaxFuncEval;
                app.FuncTolEditField_6.Value = h.stage3.FuncTol;
                app.MaxIterEditField_6.Value = h.stage3.MaxIter;
                app.PenFuncWeightEditField_6.Value = h.stage3.PenFuncWeight;
                end
                
                app.UITable_purt.Data = ['Stage 1',num2cell(   ones(1,9));...
                                         'Stage 2',num2cell(.2*ones(1,9));...
                                         'Stage 3',num2cell(.2*ones(1,9))];
                
                %                 load('SavedResults/temp_optt.mat');
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            gui_save_opt;    
%             delete(app);
                
               
        end

        % Value changed function: Stage1DropDown
        function Stage1DropDownValueChanged(app, event)
            value = app.Stage1DropDown.Value;
            app.Stage1Panel_2.Visible = 'on';
            x=7; y=20; w=275; h=385;
            switch value
                case 'TRA'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'on'
                    app.TAPanel_1.Visible = 'off'
                    app.TFPanel_1.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'on'
                    app.TAPanel_1.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_1.Visible = 'on'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'off'
                    app.ArPanel_1.Position = [x,y,w,h];
                case 'None'
                    app.Stage1Panel_2.Visible = 'off';
                    
            end
                    
        end

        % Callback function
        function Stage2CheckBoxValueChanged(app, event)
            value = app.Stage2CheckBox.Value;
            if value==1
                app.Stage2Panel.Visible = 'on';
            else 
                app.Stage2Panel.Visible = 'off';     
            end
        end

        % Value changed function: Stage2DropDown
        function Stage2DropDownValueChanged(app, event)
            value = app.Stage2DropDown.Value;
            app.Stage2Panel_2.Visible = 'on';
            
            x=298; y=20; w=275; h=385;
            
            switch value
                case 'TRA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'on'
                    app.TAPanel_2.Visible = 'off'
                    app.TFPanel_2.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'on'
                    app.TAPanel_2.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_2.Visible = 'on'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.ArPanel_2.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.Stage2Panel_2.Visible = 'off';
                 
            end
            
        end

        % Callback function
        function Stage3CheckBoxValueChanged(app, event)
            value = app.Stage3CheckBox.Value;
            if value==1
                app.Stage3Panel.Visible = 'on';
            else 
                app.Stage3Panel.Visible = 'off';     
            end
        end

        % Value changed function: Stage3DropDown
        function Stage3DropDownValueChanged(app, event)
            value = app.Stage3DropDown.Value;
            app.Stage3Panel_2.Visible = 'on';
            
            x=590; y=20; w=275; h=385;
            switch value
                case 'TRA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'on'
                    app.TAPanel_3.Visible = 'off'
                    app.TFPanel_3.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'on'
                    app.TAPanel_3.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_3.Visible = 'on'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.ArPanel_3.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.Stage3Panel_2.Visible = 'off';
            
            end
            
        end

        % Callback function
        function TRASettingsButtonPushed(app, event)
            gui_TRAoption
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationUIFigure = uifigure;
            app.OptimizationUIFigure.Name = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationUIFigure);
            app.StrategyTab = uitab(app.TabGroup);
            app.StrategyTab.Title = 'Strategy';
            app.TFPanel_1 = uipanel(app.StrategyTab);
            app.TFPanel_1.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_4 = uibuttongroup(app.TFPanel_1);
            app.CriteriaButtonGroup_4.Title = 'Criteria';
            app.MinimizemaximumForceButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizemaximumForceButton_2.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_2.Value = true;
            app.MinimizesummationofForcesButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizesummationofForcesButton_2.Text = 'Minimize summation of Forces';
            app.Mount1SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount1SelectedDirectionPanel.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalXCheckBox_4.Text = 'Local X';
            app.LocalYCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalYCheckBox_4.Text = 'Local Y';
            app.LocalZCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalZCheckBox_4.Text = 'Local Z';
            app.MagnitudeCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.MagnitudeCheckBox_4.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount2SelectedDirectionPanel.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalXCheckBox_7.Text = 'Local X';
            app.LocalYCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalYCheckBox_7.Text = 'Local Y';
            app.LocalZCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalZCheckBox_7.Text = 'Local Z';
            app.MagnitudeCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.MagnitudeCheckBox_7.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount3SelectedDirectionPanel.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalXCheckBox_8.Text = 'Local X';
            app.LocalYCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalYCheckBox_8.Text = 'Local Y';
            app.LocalZCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalZCheckBox_8.Text = 'Local Z';
            app.MagnitudeCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.MagnitudeCheckBox_8.Text = 'Magnitude';
            app.Stage1DropDownLabel = uilabel(app.StrategyTab);
            app.Stage1DropDownLabel.Text = 'Stage 1';
            app.Stage1DropDown = uidropdown(app.StrategyTab);
            app.Stage1DropDown.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.Stage1DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage1DropDownValueChanged, true);
            app.Stage1DropDown.Value = 'TRA';
            app.Stage2DropDownLabel = uilabel(app.StrategyTab);
            app.Stage2DropDownLabel.Text = 'Stage 2';
            app.Stage2DropDown = uidropdown(app.StrategyTab);
            app.Stage2DropDown.Items = {'None', 'TRA', 'TF', 'TA', 'Ar'};
            app.Stage2DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage2DropDownValueChanged, true);
            app.Stage2DropDown.Value = 'TF';
            app.Stage3DropDownLabel = uilabel(app.StrategyTab);
            app.Stage3DropDownLabel.Text = 'Stage 3';
            app.Stage3DropDown = uidropdown(app.StrategyTab);
            app.Stage3DropDown.Items = {'None', 'TRA', 'TF', 'TA', 'Ar'};
            app.Stage3DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage3DropDownValueChanged, true);
            app.Stage3DropDown.Value = 'None';
            app.ArPanel_1 = uipanel(app.StrategyTab);
            app.ArPanel_1.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_6 = uibuttongroup(app.ArPanel_1);
            app.CriteriaButtonGroup_6.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizemaximumAccelerationratioButton_2.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_2.Value = true;
            app.MinimizesummationofAccelerationratiosButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizesummationofAccelerationratiosButton_2.Text = 'Minimize summation of Acceleration ratios';
            app.Mount1SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount1SelectedDirectionPanel_3.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalXCheckBox_12.Text = 'Local X';
            app.LocalYCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalYCheckBox_12.Text = 'Local Y';
            app.LocalZCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalZCheckBox_12.Text = 'Local Z';
            app.MagnitudeCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_12.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount2SelectedDirectionPanel_3.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalXCheckBox_13.Text = 'Local X';
            app.LocalYCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalYCheckBox_13.Text = 'Local Y';
            app.LocalZCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalZCheckBox_13.Text = 'Local Z';
            app.MagnitudeCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_13.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount3SelectedDirectionPanel_3.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalXCheckBox_14.Text = 'Local X';
            app.LocalYCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalYCheckBox_14.Text = 'Local Y';
            app.LocalZCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalZCheckBox_14.Text = 'Local Z';
            app.MagnitudeCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_14.Text = 'Magnitude';
            app.TAPanel_1 = uipanel(app.StrategyTab);
            app.TAPanel_1.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_5 = uibuttongroup(app.TAPanel_1);
            app.CriteriaButtonGroup_5.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizemaximumAccelerationButton_2.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_2.Value = true;
            app.MinimizesummationofAccelerationsButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizesummationofAccelerationsButton_2.Text = 'Minimize summation of Accelerations';
            app.Mount1SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount1SelectedDirectionPanel_2.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalXCheckBox_9.Text = 'Local X';
            app.LocalYCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalYCheckBox_9.Text = 'Local Y';
            app.LocalZCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalZCheckBox_9.Text = 'Local Z';
            app.MagnitudeCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_9.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount2SelectedDirectionPanel_2.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalXCheckBox_10.Text = 'Local X';
            app.LocalYCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalYCheckBox_10.Text = 'Local Y';
            app.LocalZCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalZCheckBox_10.Text = 'Local Z';
            app.MagnitudeCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_10.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount3SelectedDirectionPanel_2.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalXCheckBox_11.Text = 'Local X';
            app.LocalYCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalYCheckBox_11.Text = 'Local Y';
            app.LocalZCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalZCheckBox_11.Text = 'Local Z';
            app.MagnitudeCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_11.Text = 'Magnitude';
            app.TFPanel_2 = uipanel(app.StrategyTab);
            app.TFPanel_2.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_7 = uibuttongroup(app.TFPanel_2);
            app.CriteriaButtonGroup_7.Title = 'Criteria';
            app.MinimizemaximumForceButton_3 = uiradiobutton(app.CriteriaButtonGroup_7);
            app.MinimizemaximumForceButton_3.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_3.Value = true;
            app.MinimizesummationofForcesButton_3 = uiradiobutton(app.CriteriaButtonGroup_7);
            app.MinimizesummationofForcesButton_3.Text = 'Minimize summation of Forces';
            app.Mount1SelectedDirectionPanel_4 = uipanel(app.TFPanel_2);
            app.Mount1SelectedDirectionPanel_4.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_15 = uicheckbox(app.Mount1SelectedDirectionPanel_4);
            app.LocalXCheckBox_15.Text = 'Local X';
            app.LocalYCheckBox_15 = uicheckbox(app.Mount1SelectedDirectionPanel_4);
            app.LocalYCheckBox_15.Text = 'Local Y';
            app.LocalZCheckBox_15 = uicheckbox(app.Mount1SelectedDirectionPanel_4);
            app.LocalZCheckBox_15.Text = 'Local Z';
            app.MagnitudeCheckBox_15 = uicheckbox(app.Mount1SelectedDirectionPanel_4);
            app.MagnitudeCheckBox_15.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_4 = uipanel(app.TFPanel_2);
            app.Mount2SelectedDirectionPanel_4.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_16 = uicheckbox(app.Mount2SelectedDirectionPanel_4);
            app.LocalXCheckBox_16.Text = 'Local X';
            app.LocalYCheckBox_16 = uicheckbox(app.Mount2SelectedDirectionPanel_4);
            app.LocalYCheckBox_16.Text = 'Local Y';
            app.LocalZCheckBox_16 = uicheckbox(app.Mount2SelectedDirectionPanel_4);
            app.LocalZCheckBox_16.Text = 'Local Z';
            app.MagnitudeCheckBox_16 = uicheckbox(app.Mount2SelectedDirectionPanel_4);
            app.MagnitudeCheckBox_16.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_4 = uipanel(app.TFPanel_2);
            app.Mount3SelectedDirectionPanel_4.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_17 = uicheckbox(app.Mount3SelectedDirectionPanel_4);
            app.LocalXCheckBox_17.Text = 'Local X';
            app.LocalYCheckBox_17 = uicheckbox(app.Mount3SelectedDirectionPanel_4);
            app.LocalYCheckBox_17.Text = 'Local Y';
            app.LocalZCheckBox_17 = uicheckbox(app.Mount3SelectedDirectionPanel_4);
            app.LocalZCheckBox_17.Text = 'Local Z';
            app.MagnitudeCheckBox_17 = uicheckbox(app.Mount3SelectedDirectionPanel_4);
            app.MagnitudeCheckBox_17.Text = 'Magnitude';
            app.TAPanel_2 = uipanel(app.StrategyTab);
            app.TAPanel_2.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_8 = uibuttongroup(app.TAPanel_2);
            app.CriteriaButtonGroup_8.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_3 = uiradiobutton(app.CriteriaButtonGroup_8);
            app.MinimizemaximumAccelerationButton_3.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_3.Value = true;
            app.MinimizesummationofAccelerationsButton_3 = uiradiobutton(app.CriteriaButtonGroup_8);
            app.MinimizesummationofAccelerationsButton_3.Text = 'Minimize summation of Accelerations';
            app.Mount1SelectedDirectionPanel_5 = uipanel(app.TAPanel_2);
            app.Mount1SelectedDirectionPanel_5.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_18 = uicheckbox(app.Mount1SelectedDirectionPanel_5);
            app.LocalXCheckBox_18.Text = 'Local X';
            app.LocalYCheckBox_18 = uicheckbox(app.Mount1SelectedDirectionPanel_5);
            app.LocalYCheckBox_18.Text = 'Local Y';
            app.LocalZCheckBox_18 = uicheckbox(app.Mount1SelectedDirectionPanel_5);
            app.LocalZCheckBox_18.Text = 'Local Z';
            app.MagnitudeCheckBox_18 = uicheckbox(app.Mount1SelectedDirectionPanel_5);
            app.MagnitudeCheckBox_18.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_5 = uipanel(app.TAPanel_2);
            app.Mount2SelectedDirectionPanel_5.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_19 = uicheckbox(app.Mount2SelectedDirectionPanel_5);
            app.LocalXCheckBox_19.Text = 'Local X';
            app.LocalYCheckBox_19 = uicheckbox(app.Mount2SelectedDirectionPanel_5);
            app.LocalYCheckBox_19.Text = 'Local Y';
            app.LocalZCheckBox_19 = uicheckbox(app.Mount2SelectedDirectionPanel_5);
            app.LocalZCheckBox_19.Text = 'Local Z';
            app.MagnitudeCheckBox_19 = uicheckbox(app.Mount2SelectedDirectionPanel_5);
            app.MagnitudeCheckBox_19.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_5 = uipanel(app.TAPanel_2);
            app.Mount3SelectedDirectionPanel_5.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_20 = uicheckbox(app.Mount3SelectedDirectionPanel_5);
            app.LocalXCheckBox_20.Text = 'Local X';
            app.LocalYCheckBox_20 = uicheckbox(app.Mount3SelectedDirectionPanel_5);
            app.LocalYCheckBox_20.Text = 'Local Y';
            app.LocalZCheckBox_20 = uicheckbox(app.Mount3SelectedDirectionPanel_5);
            app.LocalZCheckBox_20.Text = 'Local Z';
            app.MagnitudeCheckBox_20 = uicheckbox(app.Mount3SelectedDirectionPanel_5);
            app.MagnitudeCheckBox_20.Text = 'Magnitude';
            app.ArPanel_2 = uipanel(app.StrategyTab);
            app.ArPanel_2.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_9 = uibuttongroup(app.ArPanel_2);
            app.CriteriaButtonGroup_9.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_3 = uiradiobutton(app.CriteriaButtonGroup_9);
            app.MinimizemaximumAccelerationratioButton_3.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_3.Value = true;
            app.MinimizesummationofAccelerationratiosButton_3 = uiradiobutton(app.CriteriaButtonGroup_9);
            app.MinimizesummationofAccelerationratiosButton_3.Text = 'Minimize summation of Acceleration ratios';
            app.Mount1SelectedDirectionPanel_6 = uipanel(app.ArPanel_2);
            app.Mount1SelectedDirectionPanel_6.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_21 = uicheckbox(app.Mount1SelectedDirectionPanel_6);
            app.LocalXCheckBox_21.Text = 'Local X';
            app.LocalYCheckBox_21 = uicheckbox(app.Mount1SelectedDirectionPanel_6);
            app.LocalYCheckBox_21.Text = 'Local Y';
            app.LocalZCheckBox_21 = uicheckbox(app.Mount1SelectedDirectionPanel_6);
            app.LocalZCheckBox_21.Text = 'Local Z';
            app.MagnitudeCheckBox_21 = uicheckbox(app.Mount1SelectedDirectionPanel_6);
            app.MagnitudeCheckBox_21.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_6 = uipanel(app.ArPanel_2);
            app.Mount2SelectedDirectionPanel_6.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_22 = uicheckbox(app.Mount2SelectedDirectionPanel_6);
            app.LocalXCheckBox_22.Text = 'Local X';
            app.LocalYCheckBox_22 = uicheckbox(app.Mount2SelectedDirectionPanel_6);
            app.LocalYCheckBox_22.Text = 'Local Y';
            app.LocalZCheckBox_22 = uicheckbox(app.Mount2SelectedDirectionPanel_6);
            app.LocalZCheckBox_22.Text = 'Local Z';
            app.MagnitudeCheckBox_22 = uicheckbox(app.Mount2SelectedDirectionPanel_6);
            app.MagnitudeCheckBox_22.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_6 = uipanel(app.ArPanel_2);
            app.Mount3SelectedDirectionPanel_6.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_23 = uicheckbox(app.Mount3SelectedDirectionPanel_6);
            app.LocalXCheckBox_23.Text = 'Local X';
            app.LocalYCheckBox_23 = uicheckbox(app.Mount3SelectedDirectionPanel_6);
            app.LocalYCheckBox_23.Text = 'Local Y';
            app.LocalZCheckBox_23 = uicheckbox(app.Mount3SelectedDirectionPanel_6);
            app.LocalZCheckBox_23.Text = 'Local Z';
            app.MagnitudeCheckBox_23 = uicheckbox(app.Mount3SelectedDirectionPanel_6);
            app.MagnitudeCheckBox_23.Text = 'Magnitude';
            app.TFPanel_3 = uipanel(app.StrategyTab);
            app.TFPanel_3.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_10 = uibuttongroup(app.TFPanel_3);
            app.CriteriaButtonGroup_10.Title = 'Criteria';
            app.MinimizemaximumForceButton_4 = uiradiobutton(app.CriteriaButtonGroup_10);
            app.MinimizemaximumForceButton_4.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_4.Value = true;
            app.MinimizesummationofForcesButton_4 = uiradiobutton(app.CriteriaButtonGroup_10);
            app.MinimizesummationofForcesButton_4.Text = 'Minimize summation of Forces';
            app.Mount1SelectedDirectionPanel_7 = uipanel(app.TFPanel_3);
            app.Mount1SelectedDirectionPanel_7.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_24 = uicheckbox(app.Mount1SelectedDirectionPanel_7);
            app.LocalXCheckBox_24.Text = 'Local X';
            app.LocalYCheckBox_24 = uicheckbox(app.Mount1SelectedDirectionPanel_7);
            app.LocalYCheckBox_24.Text = 'Local Y';
            app.LocalZCheckBox_24 = uicheckbox(app.Mount1SelectedDirectionPanel_7);
            app.LocalZCheckBox_24.Text = 'Local Z';
            app.MagnitudeCheckBox_24 = uicheckbox(app.Mount1SelectedDirectionPanel_7);
            app.MagnitudeCheckBox_24.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_7 = uipanel(app.TFPanel_3);
            app.Mount2SelectedDirectionPanel_7.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_25 = uicheckbox(app.Mount2SelectedDirectionPanel_7);
            app.LocalXCheckBox_25.Text = 'Local X';
            app.LocalYCheckBox_25 = uicheckbox(app.Mount2SelectedDirectionPanel_7);
            app.LocalYCheckBox_25.Text = 'Local Y';
            app.LocalZCheckBox_25 = uicheckbox(app.Mount2SelectedDirectionPanel_7);
            app.LocalZCheckBox_25.Text = 'Local Z';
            app.MagnitudeCheckBox_25 = uicheckbox(app.Mount2SelectedDirectionPanel_7);
            app.MagnitudeCheckBox_25.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_7 = uipanel(app.TFPanel_3);
            app.Mount3SelectedDirectionPanel_7.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_26 = uicheckbox(app.Mount3SelectedDirectionPanel_7);
            app.LocalXCheckBox_26.Text = 'Local X';
            app.LocalYCheckBox_26 = uicheckbox(app.Mount3SelectedDirectionPanel_7);
            app.LocalYCheckBox_26.Text = 'Local Y';
            app.LocalZCheckBox_26 = uicheckbox(app.Mount3SelectedDirectionPanel_7);
            app.LocalZCheckBox_26.Text = 'Local Z';
            app.MagnitudeCheckBox_26 = uicheckbox(app.Mount3SelectedDirectionPanel_7);
            app.MagnitudeCheckBox_26.Text = 'Magnitude';
            app.TAPanel_3 = uipanel(app.StrategyTab);
            app.TAPanel_3.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_11 = uibuttongroup(app.TAPanel_3);
            app.CriteriaButtonGroup_11.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_4 = uiradiobutton(app.CriteriaButtonGroup_11);
            app.MinimizemaximumAccelerationButton_4.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_4.Value = true;
            app.MinimizesummationofAccelerationsButton_4 = uiradiobutton(app.CriteriaButtonGroup_11);
            app.MinimizesummationofAccelerationsButton_4.Text = 'Minimize summation of Accelerations';
            app.Mount1SelectedDirectionPanel_8 = uipanel(app.TAPanel_3);
            app.Mount1SelectedDirectionPanel_8.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_27 = uicheckbox(app.Mount1SelectedDirectionPanel_8);
            app.LocalXCheckBox_27.Text = 'Local X';
            app.LocalYCheckBox_27 = uicheckbox(app.Mount1SelectedDirectionPanel_8);
            app.LocalYCheckBox_27.Text = 'Local Y';
            app.LocalZCheckBox_27 = uicheckbox(app.Mount1SelectedDirectionPanel_8);
            app.LocalZCheckBox_27.Text = 'Local Z';
            app.MagnitudeCheckBox_27 = uicheckbox(app.Mount1SelectedDirectionPanel_8);
            app.MagnitudeCheckBox_27.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_8 = uipanel(app.TAPanel_3);
            app.Mount2SelectedDirectionPanel_8.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_28 = uicheckbox(app.Mount2SelectedDirectionPanel_8);
            app.LocalXCheckBox_28.Text = 'Local X';
            app.LocalYCheckBox_28 = uicheckbox(app.Mount2SelectedDirectionPanel_8);
            app.LocalYCheckBox_28.Text = 'Local Y';
            app.LocalZCheckBox_28 = uicheckbox(app.Mount2SelectedDirectionPanel_8);
            app.LocalZCheckBox_28.Text = 'Local Z';
            app.MagnitudeCheckBox_28 = uicheckbox(app.Mount2SelectedDirectionPanel_8);
            app.MagnitudeCheckBox_28.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_8 = uipanel(app.TAPanel_3);
            app.Mount3SelectedDirectionPanel_8.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_29 = uicheckbox(app.Mount3SelectedDirectionPanel_8);
            app.LocalXCheckBox_29.Text = 'Local X';
            app.LocalYCheckBox_29 = uicheckbox(app.Mount3SelectedDirectionPanel_8);
            app.LocalYCheckBox_29.Text = 'Local Y';
            app.LocalZCheckBox_29 = uicheckbox(app.Mount3SelectedDirectionPanel_8);
            app.LocalZCheckBox_29.Text = 'Local Z';
            app.MagnitudeCheckBox_29 = uicheckbox(app.Mount3SelectedDirectionPanel_8);
            app.MagnitudeCheckBox_29.Text = 'Magnitude';
            app.ArPanel_3 = uipanel(app.StrategyTab);
            app.ArPanel_3.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_12 = uibuttongroup(app.ArPanel_3);
            app.CriteriaButtonGroup_12.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_4 = uiradiobutton(app.CriteriaButtonGroup_12);
            app.MinimizemaximumAccelerationratioButton_4.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_4.Value = true;
            app.MinimizesummationofAccelerationratiosButton_4 = uiradiobutton(app.CriteriaButtonGroup_12);
            app.MinimizesummationofAccelerationratiosButton_4.Text = 'Minimize summation of Acceleration ratios';
            app.Mount1SelectedDirectionPanel_9 = uipanel(app.ArPanel_3);
            app.Mount1SelectedDirectionPanel_9.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_30 = uicheckbox(app.Mount1SelectedDirectionPanel_9);
            app.LocalXCheckBox_30.Text = 'Local X';
            app.LocalYCheckBox_30 = uicheckbox(app.Mount1SelectedDirectionPanel_9);
            app.LocalYCheckBox_30.Text = 'Local Y';
            app.LocalZCheckBox_30 = uicheckbox(app.Mount1SelectedDirectionPanel_9);
            app.LocalZCheckBox_30.Text = 'Local Z';
            app.MagnitudeCheckBox_30 = uicheckbox(app.Mount1SelectedDirectionPanel_9);
            app.MagnitudeCheckBox_30.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_9 = uipanel(app.ArPanel_3);
            app.Mount2SelectedDirectionPanel_9.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_31 = uicheckbox(app.Mount2SelectedDirectionPanel_9);
            app.LocalXCheckBox_31.Text = 'Local X';
            app.LocalYCheckBox_31 = uicheckbox(app.Mount2SelectedDirectionPanel_9);
            app.LocalYCheckBox_31.Text = 'Local Y';
            app.LocalZCheckBox_31 = uicheckbox(app.Mount2SelectedDirectionPanel_9);
            app.LocalZCheckBox_31.Text = 'Local Z';
            app.MagnitudeCheckBox_31 = uicheckbox(app.Mount2SelectedDirectionPanel_9);
            app.MagnitudeCheckBox_31.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_9 = uipanel(app.ArPanel_3);
            app.Mount3SelectedDirectionPanel_9.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_32 = uicheckbox(app.Mount3SelectedDirectionPanel_9);
            app.LocalXCheckBox_32.Text = 'Local X';
            app.LocalYCheckBox_32 = uicheckbox(app.Mount3SelectedDirectionPanel_9);
            app.LocalYCheckBox_32.Text = 'Local Y';
            app.LocalZCheckBox_32 = uicheckbox(app.Mount3SelectedDirectionPanel_9);
            app.LocalZCheckBox_32.Text = 'Local Z';
            app.MagnitudeCheckBox_32 = uicheckbox(app.Mount3SelectedDirectionPanel_9);
            app.MagnitudeCheckBox_32.Text = 'Magnitude';
            app.ConstraintsTab_2 = uitab(app.TabGroup);
            app.ConstraintsTab_2.Title = 'Constraints';
            app.KEDcriteriaCheckBox = uicheckbox(app.ConstraintsTab_2);
            app.KEDcriteriaCheckBox.Text = 'KED criteria';
            app.KEDcriteriaCheckBox.Value = true;
            app.percentageEditFieldLabel = uilabel(app.ConstraintsTab_2);
            app.percentageEditFieldLabel.Text = 'percentage';
            app.percentageEditField = uieditfield(app.ConstraintsTab_2, 'numeric');
            app.percentageEditField.Value = 85;
            app.MountloactionorientationButton = uibutton(app.ConstraintsTab_2, 'push');
            app.MountloactionorientationButton.Text = 'Mount loaction & orientation';
            app.NaturalFrequenciesPanel = uipanel(app.ConstraintsTab_2);
            app.NaturalFrequenciesPanel.Title = 'Natural Frequencies';
            app.LongitudinalLabel_3 = uilabel(app.NaturalFrequenciesPanel);
            app.LongitudinalLabel_3.Text = 'Longitudinal';
            app.LateralLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.LateralLabel_4.Text = 'Lateral';
            app.BounceLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_4.Text = 'Bounce';
            app.RollLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.RollLabel_4.Text = 'Roll';
            app.PitchLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.PitchLabel_4.Text = 'Pitch';
            app.BounceLabel_5 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_5.Text = 'Bounce';
            app.EditField_31 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.LowerHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.LowerHzEditFieldLabel_2.Text = 'Lower (Hz)';
            app.EditField_32 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.UpperHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.UpperHzEditFieldLabel_2.Text = 'Upper (Hz)';
            app.EditField_33 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_34 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_35 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_36 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_37 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_38 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_39 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_40 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_41 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_42 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.StaticDeflectionsPanel = uipanel(app.ConstraintsTab_2);
            app.StaticDeflectionsPanel.Title = 'Static ِDeflections';
            app.LongitudinalLabel_2 = uilabel(app.StaticDeflectionsPanel);
            app.LongitudinalLabel_2.Text = 'Longitudinal';
            app.mmLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_3.Text = 'mm';
            app.LateralLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.LateralLabel_3.Text = 'Lateral';
            app.mmLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_4.Text = 'mm';
            app.BounceLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.BounceLabel_3.Text = 'Bounce';
            app.mmLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_5.Text = 'mm';
            app.RollLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.RollLabel_3.Text = 'Roll';
            app.degLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_4.Text = 'deg';
            app.PitchLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.PitchLabel_3.Text = 'Pitch';
            app.degLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_5.Text = 'deg';
            app.YawLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.YawLabel_3.Text = 'Yaw';
            app.degLabel_6 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_6.Text = 'deg';
            app.EditField_25 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_26 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_27 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_28 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_29 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_30 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.UITable_purt = uitable(app.ConstraintsTab_2);
            app.UITable_purt.ColumnName = {''; 'r_1'; 'r_2'; 'r_3'; 'o_1'; 'o_2'; 'o_3'; 'k_1'; 'k_2'; 'k_3'};
            app.UITable_purt.RowName = {};
            app.UITable_purt.ColumnEditable = [false true true true true true true true true true];
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Stage1Panel_2 = uipanel(app.OptionsTab);
            app.Stage1Panel_2.Title = 'Stage 1';
            app.swarmsizeEditField_4Label = uilabel(app.Stage1Panel_2);
            app.swarmsizeEditField_4Label.Text = 'swarmsize';
            app.swarmsizeEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.MaxFuncEvalEditField_4Label = uilabel(app.Stage1Panel_2);
            app.MaxFuncEvalEditField_4Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.FuncTolEditField_4Label = uilabel(app.Stage1Panel_2);
            app.FuncTolEditField_4Label.Text = 'FuncTol';
            app.FuncTolEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.MaxIterEditField_4Label = uilabel(app.Stage1Panel_2);
            app.MaxIterEditField_4Label.Text = 'MaxIter';
            app.MaxIterEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.PenFuncWeightEditField_4Label = uilabel(app.Stage1Panel_2);
            app.PenFuncWeightEditField_4Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.Stage2Panel_2 = uipanel(app.OptionsTab);
            app.Stage2Panel_2.Title = 'Stage 2';
            app.swarmsizeEditField_5Label = uilabel(app.Stage2Panel_2);
            app.swarmsizeEditField_5Label.Text = 'swarmsize';
            app.swarmsizeEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.MaxFuncEvalEditField_5Label = uilabel(app.Stage2Panel_2);
            app.MaxFuncEvalEditField_5Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.FuncTolEditField_5Label = uilabel(app.Stage2Panel_2);
            app.FuncTolEditField_5Label.Text = 'FuncTol';
            app.FuncTolEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.MaxIterEditField_5Label = uilabel(app.Stage2Panel_2);
            app.MaxIterEditField_5Label.Text = 'MaxIter';
            app.MaxIterEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.PenFuncWeightEditField_5Label = uilabel(app.Stage2Panel_2);
            app.PenFuncWeightEditField_5Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.Stage3Panel_2 = uipanel(app.OptionsTab);
            app.Stage3Panel_2.Title = 'Stage 3';
            app.swarmsizeEditField_6Label = uilabel(app.Stage3Panel_2);
            app.swarmsizeEditField_6Label.Text = 'swarmsize';
            app.swarmsizeEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.MaxFuncEvalEditField_6Label = uilabel(app.Stage3Panel_2);
            app.MaxFuncEvalEditField_6Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.FuncTolEditField_6Label = uilabel(app.Stage3Panel_2);
            app.FuncTolEditField_6Label.Text = 'FuncTol';
            app.FuncTolEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.MaxIterEditField_6Label = uilabel(app.Stage3Panel_2);
            app.MaxIterEditField_6Label.Text = 'MaxIter';
            app.MaxIterEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.PenFuncWeightEditField_6Label = uilabel(app.Stage3Panel_2);
            app.PenFuncWeightEditField_6Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.CloseButton = uibutton(app.OptimizationUIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.OptimizationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OptimizationLabel_2 = uilabel(app.OptimizationUIFigure);
            app.OptimizationLabel_2.Text = 'Optimization';
            app.OptimizationFileEditFieldLabel = uilabel(app.OptimizationUIFigure);
            app.OptimizationFileEditFieldLabel.Text = 'Optimization File :';
            app.OptimizationFileEditField = uieditfield(app.OptimizationUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_optimization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationUIFigure)
        end
    end
end